classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        IntroTab                      matlab.ui.container.Tab
        Image                         matlab.ui.control.Image
        RIGELSLabel                   matlab.ui.control.Label
        presentaLabel                 matlab.ui.control.Label
        ESTRELLITALabel               matlab.ui.control.Label
        CinDirTab                     matlab.ui.container.Tab
        ModomanualPanel               matlab.ui.container.Panel
        MOTOR1SliderLabel             matlab.ui.control.Label
        MOTOR1Slider                  matlab.ui.control.Slider
        MOTOR2SliderLabel             matlab.ui.control.Label
        MOTOR2Slider                  matlab.ui.control.Slider
        MOTOR3SliderLabel             matlab.ui.control.Label
        MOTOR3Slider                  matlab.ui.control.Slider
        POSICINDELEFECTORLabel        matlab.ui.control.Label
        CoordenadasfinalesPanel       matlab.ui.container.Panel
        XLabel                        matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        posicinenXLabel               matlab.ui.control.Label
        posicinenYLabel               matlab.ui.control.Label
        posicinenZLabel               matlab.ui.control.Label
        CinemticaDirectaPanel         matlab.ui.container.Panel
        Thera1EditFieldLabel          matlab.ui.control.Label
        Theta1Dir                     matlab.ui.control.NumericEditField
        Thera2EditFieldLabel          matlab.ui.control.Label
        Theta2Dir                     matlab.ui.control.NumericEditField
        Thera3EditFieldLabel          matlab.ui.control.Label
        Theta3Dir                     matlab.ui.control.NumericEditField
        MOVEButton                    matlab.ui.control.Button
        HOMEButton                    matlab.ui.control.Button
        axesDir                       matlab.ui.control.UIAxes
        CinInvTab                     matlab.ui.container.Tab
        CinemticaInversaPanel         matlab.ui.container.Panel
        XEditFieldLabel               matlab.ui.control.Label
        Xinv                          matlab.ui.control.NumericEditField
        PosicindeseadadelefectorfinalLabel  matlab.ui.control.Label
        SENDButton                    matlab.ui.control.Button
        YEditFieldLabel               matlab.ui.control.Label
        Yinv                          matlab.ui.control.NumericEditField
        ZEditFieldLabel               matlab.ui.control.Label
        Zinv                          matlab.ui.control.NumericEditField
        PosiblessolucionesDropDownLabel  matlab.ui.control.Label
        PosiblessolucionesDropDown    matlab.ui.control.DropDown
        ngulosporjuntaPanel           matlab.ui.container.Panel
        Thera1EditField_2Label        matlab.ui.control.Label
        Theta1Inv                     matlab.ui.control.NumericEditField
        Thera2EditField_2Label        matlab.ui.control.Label
        Theta2Inv                     matlab.ui.control.NumericEditField
        Thera3EditField_2Label        matlab.ui.control.Label
        Theta3Inv                     matlab.ui.control.NumericEditField
        PosiblessolucionesPanel       matlab.ui.container.Panel
        Solucin1Panel                 matlab.ui.container.Panel
        Thera1EditField_2Label_2      matlab.ui.control.Label
        Theta1Sol1                    matlab.ui.control.NumericEditField
        Thera2EditField_2Label_2      matlab.ui.control.Label
        Theta2Sol1                    matlab.ui.control.NumericEditField
        Thera3EditField_2Label_2      matlab.ui.control.Label
        Theta3Sol1                    matlab.ui.control.NumericEditField
        Solucin2Panel                 matlab.ui.container.Panel
        Thera1EditField_2Label_3      matlab.ui.control.Label
        Theta1Sol2                    matlab.ui.control.NumericEditField
        Thera2EditField_2Label_3      matlab.ui.control.Label
        Theta2Sol2                    matlab.ui.control.NumericEditField
        Thera3EditField_2Label_3      matlab.ui.control.Label
        Theta3Sol2                    matlab.ui.control.NumericEditField
        Solucin3Panel                 matlab.ui.container.Panel
        Thera1EditField_2Label_4      matlab.ui.control.Label
        Theta1Sol3                    matlab.ui.control.NumericEditField
        Thera2EditField_2Label_4      matlab.ui.control.Label
        Theta2Sol3                    matlab.ui.control.NumericEditField
        Thera3EditField_2Label_4      matlab.ui.control.Label
        Theta3Sol3                    matlab.ui.control.NumericEditField
        Solucin4Panel                 matlab.ui.container.Panel
        Thera1EditField_2Label_5      matlab.ui.control.Label
        Theta1Sol4                    matlab.ui.control.NumericEditField
        Thera2EditField_2Label_5      matlab.ui.control.Label
        Theta2Sol4                    matlab.ui.control.NumericEditField
        Thera3EditField_2Label_5      matlab.ui.control.Label
        Theta3Sol4                    matlab.ui.control.NumericEditField
        solElegida                    matlab.ui.control.Label
        axesInv                       matlab.ui.control.UIAxes
        TrayectoriasTab               matlab.ui.container.Tab
        TrayectoriasPanel             matlab.ui.container.Panel
        SeleccionalatrayectoriaLabel  matlab.ui.control.Label
        CirculoButton                 matlab.ui.control.Button
        EspiralButton                 matlab.ui.control.Button
        ConstelacinButton             matlab.ui.control.Button
        axesTrayectoria               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PosiblessolucionesDropDown
        function PosiblessolucionesDropDownValueChanged(app, event)
            value = app.PosiblessolucionesDropDown.Value
            PX = getGlobal_PX;
            PY = getGlobal_PY;
            PZ = getGlobal_PZ;
            [Salida, label, allSol]=IGM(PX,PY,PZ);
            SalidaRad = Salida
            
            x = length(label)
            pos_sel=0;
            S0 = [0 0 0];
            S1 = 0; S2 = 0; S3 = 0; S4 = 0;
            Salida = rad2deg(Salida)
            j=0;
            %Soluciones del IGM disponibles
            for j = 1:x %Parte de la 2 porque en la 1 esta el default
                if(label(j) == "Solución 1")
                    S1 = Salida(:,:,j)
                    pos_sel1 = j;
                elseif(label(j) == "Solución 2")
                    S2 = Salida(:,:,j)
                    pos_sel2 = j;
                elseif(label(j) == "Solución 3")
                    S3 = Salida(:,:,j)
                    pos_sel3 = j;
                elseif(label(j) == "Solución 4")
                    S4 = Salida(:,:,j)
                    pos_sel4 = j;
                end
            end
%             S3
%             label
%             SalidaRad
            sel = 1; %default - el de ceros
            t1 = getGlobal_theta1;t2 = getGlobal_theta2;t3 = getGlobal_theta3;
            % --------------Aquí va el if de la solución seeccionada por el usuario
            if (value == "Seleccione")
                %S0 = Salida(:,:,1);
                %[theta1, theta2, theta3] = setGlobal_thetas(S0(1),S0(2),S0(3));
                app.solElegida.Text = "SOLUCIÓN ELEGIDA:  Ninguna";
            elseif (value == "Solución 1")
                [theta1, theta2, theta3] = setGlobal_thetas(S1(1),S1(2),S1(3));
                sel = pos_sel1;
                app.solElegida.Text = "SOLUCIÓN ELEGIDA: Solucion 1";
            elseif (value == "Solución 2")
                [theta1, theta2, theta3] = setGlobal_thetas(S2(1),S2(2),S2(3));
                sel = pos_sel2;
                app.solElegida.Text = "SOLUCIÓN ELEGIDA:  Solucion 2";
            elseif (value == "Solución 3")
                [theta1, theta2, theta3] = setGlobal_thetas(S3(1),S3(2),S3(3));
                sel = pos_sel3;
                app.solElegida.Text = "SOLUCIÓN ELEGIDA:  Solucion 3";
            elseif (value == "Solución 4")
                [theta1, theta2, theta3] = setGlobal_thetas(S4(1),S4(2),S4(3));
                sel = pos_sel4;
                app.solElegida.Text = "SOLUCIÓN ELEGIDA:  Solucion 4";
            end       
            % La inversa ya te agrega la compensación (le quita los 90)
            % No como en la directa que tenemos que agg la comp
            setGlobal_theta3(getGlobal_theta3);
            %Funcion pa resolver el DH de nuestro robot
        %    [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
             [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0], SalidaRad(:,:,sel) ,SalidaRad(:,:,sel)) 

            MatrizInterm = round(MatrizInterm); %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
            app.MOTOR1Slider.Value = getGlobal_theta1;
            app.MOTOR2Slider.Value = getGlobal_theta2;
            app.MOTOR3Slider.Value = getGlobal_theta3+90 %+180
            %Thetas en Dir e Inv
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta2Dir.Value = getGlobal_theta2;
            app.Theta3Dir.Value = getGlobal_theta3+90; %Debería salir 0 o -90? --- le quite +180 a 90
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">") % aqui cambiamos +90 a +180 en el theta3
            writeline(device,theta_array) 
            pause(2.0) 
            clear device 
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
            x = app.Xinv.Value;
            y = app.Yinv.Value;
            z = app.Zinv.Value;
            setGlobal_PX(x);
            setGlobal_PY(y);
            setGlobal_PZ(z);
            [~,labels, allSol]=IGM(x,y,z);
            app.PosiblessolucionesDropDown.Items=labels;
            setGlobal_PX(app.Xinv.Value);
            setGlobal_PY(app.Yinv.Value);
            setGlobal_PZ(app.Zinv.Value);
            
            allSolDeg = rad2deg(allSol);
            S1 = allSolDeg(:,:,1);
            S2 = allSolDeg(:,:,2);
            S3 = allSolDeg(:,:,3);
            S4 = allSolDeg(:,:,4);

            %Para desplegar en la GUI- Todas las sol posibles
            %---------SOL1------------
            app.Theta1Sol1.Value = S1(1);
            app.Theta2Sol1.Value = S1(2);
            app.Theta3Sol1.Value = S1(3)+90;
            %---------SOL2------------
            app.Theta1Sol2.Value = S2(1);
            app.Theta2Sol2.Value = S2(2);
            app.Theta3Sol2.Value = S2(3)+90;
            %---------SOL3------------
            app.Theta1Sol3.Value = S3(1);
            app.Theta2Sol3.Value = S3(2);
            app.Theta3Sol3.Value = S3(3)+90;
            %---------SOL4------------
            app.Theta1Sol4.Value = S4(1);
            app.Theta2Sol4.Value = S4(2);
            app.Theta3Sol4.Value = S4(3)+90;

        end

        % Button pushed function: MOVEButton
        function MOVEButtonPushed(app, event)
            theta1 = app.Theta1Dir.Value;
            theta2 = app.Theta2Dir.Value;
            theta3 = app.Theta3Dir.Value;
            setGlobal_theta1(theta1);
            setGlobal_theta2(theta2);
            setGlobal_theta3(theta3-90);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
            app.MOTOR1Slider.Value = getGlobal_theta1;
            app.MOTOR2Slider.Value = getGlobal_theta2;
            app.MOTOR3Slider.Value = getGlobal_theta3+90;

            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            [theta1, theta2, theta3] = setGlobal_thetas(0,0,0);
            %Compensacion en punto
            setGlobal_theta3(getGlobal_theta3-90);
            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));
            
            %Para cambiar graficamente el slider
            app.MOTOR1Slider.Value = getGlobal_theta1;
            app.MOTOR2Slider.Value = getGlobal_theta2;
            app.MOTOR3Slider.Value = getGlobal_theta3+90;
            %Thetas en Dir e Inv
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta2Dir.Value = getGlobal_theta2;
            app.Theta3Dir.Value = getGlobal_theta3+90; %Debería salir 0 o -90? 
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            app.Theta2Inv.Value = getGlobal_theta2;
            app.Theta3Inv.Value = getGlobal_theta3+90;
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device      
            
        end

        % Value changed function: MOTOR1Slider
        function MOTOR1SliderValueChanged(app, event)
            theta1 = app.MOTOR1Slider.Value %Deberia hacer uno con *changing func
            setGlobal_theta1(theta1);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            app.Theta1Dir.Value = getGlobal_theta1; %--Dir
            app.Theta1Inv.Value = getGlobal_theta1; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
            
        end

        % Value changed function: MOTOR2Slider
        function MOTOR2SliderValueChanged(app, event)
            theta2 = app.MOTOR2Slider.Value;
            setGlobal_theta2(theta2);

            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            app.Theta2Dir.Value = getGlobal_theta2; %--Dir
            app.Theta2Inv.Value = getGlobal_theta2; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end

        % Value changed function: MOTOR3Slider
        function MOTOR3SliderValueChanged(app, event)
            theta3 = app.MOTOR3Slider.Value
            setGlobal_theta3(theta3-90);
            
            %lo convertimos en radianes?
            %Funcion pa resolver el DH de nuestro robot
            [T, MatrizParcial,MatrizInterm] = GENDGM([0 0 0],[0 88.1 153.59],[pi/2 -pi -pi/2],[25.5 0 0],[0 0 0],[getGlobal_theta1*pi/180 getGlobal_theta2*pi/180 getGlobal_theta3*pi/180]) 
            MatrizInterm = round(MatrizInterm) %se redondea porque else no se ve identico al real
            T_1=MatrizInterm(:,:,1); %Junta 1
            T_2=MatrizInterm(:,:,2); %Junta 2
            T_3=MatrizInterm(:,:,3); %Junta 3
            %Separacion de coordenadas a graficar
            X = [0 T_1(1,4) T_2(1,4) T_3(1,4)]
            Y = [0 T_1(2,4) T_2(2,4) T_3(2,4)]
            Z = [0 T_1(3,4) T_2(3,4) T_3(3,4)]

            %---------------------1) Cambios en la GUI
            % ----   +90 para que no tenga el offset
            app.Theta3Dir.Value = getGlobal_theta3+90; %--Dir 
            app.Theta3Inv.Value = getGlobal_theta3+90; %--Inv
            
            plot3(app.axesDir,X,Y,Z,'-o','Color',[0.5 0 0.8]);
            plot3(app.axesInv,X,Y,Z,'-o','Color',[0.5 0 0.8]);

            %Posiciones iniciales con thetas = 0
            app.posicinenXLabel.Text = int2str(round(T(13))); %--Dir
            app.posicinenYLabel.Text = int2str(round(T(14)));
            app.posicinenZLabel.Text = int2str(round(T(15)));
            app.Xinv.Value = round(T(13)); %--Inv
            app.Yinv.Value = round(T(14));
            app.Zinv.Value = round(T(15));

                        
            %-----------------------2)Datos al Arduino
            device=serialport("COM3",9600); 
            pause(1.0) 
            %-----------------------ESCRIBE------------------ %le sumamos
            %90
            theta_array=strcat("<",int2str(getGlobal_theta1),",",int2str(getGlobal_theta2),",",int2str(getGlobal_theta3+90),">")
            writeline(device,theta_array) 
            pause(2.0) 
            clear device
        end

        % Button pushed function: CirculoButton
        function CirculoButtonPushed(app, event)
            %Aquí poner el codigo del circulo
            L1 = 25.5; 
            L2 = 88.1; 
            L3 = 153.59;
            
            t = 1;    %Time variable
            r = 60;   %Radius of the circle
            
            device=serialport("COM3",9600); 
            
            for c=-pi:0.1:pi 

                t = t+1;
            
                Px = (r*cos(c))+100;   %X coordinate of the circle
                Py = (r*sin(c))+50;     %Y coordinate of the circle
                Pz = 150;               %Z coordinate of the circle
                
                X(t) = Px;             %Variable to store the X coordinate
                Y(t) = Py;             %Variable to store the Y coordinate
                Z(t) = Pz;             %Variable to store the Z coordinate
                
                % Retrieve of the first angle Q1
                q1 = atan2(Py,Px);
                
                Z1 = Px*cos(q1)+Py*sin(q1);
                Z2 = Pz-L1;
            
                % Retrieve of the second angle Q3
                C3 = (Z1^2 + Z2^2 - L2^2 - L3^2)/(2*L2*L3);
                S3 = sqrt(1-C3^2);
                q3 = atan2(S3,C3);
            
               % Retrieve if the third angle Q2 
                B1 = L2 + L3*C3;
                B2 = L3*S3;
            
                S2 = (B1*Z2 + B2*Z1)/(B1^2 + B2^2);
                C2 = (B1*Z1 - B2*Z2)/(B1^2 + B2^2);
                q2 = atan2(S2,C2);
                
                %Esto es el plot del circulo
                plot3(app.axesTrayectoria,X(2:t),Y(2:t),Z(2:t),'-o','Color',[0.5 0 0.8])   
                pause(2.0)
                
                q11 = rad2deg(q1);
                q22 = rad2deg(q2);
                q33 = rad2deg(q3);
            
                theta_array=strcat("<",int2str(q11),",",int2str(q22),",",int2str(q33+90),">")
                writeline(device,theta_array) 

            end
            clear device
      
        end

        % Button pushed function: EspiralButton
        function EspiralButtonPushed(app, event)
            %Aquí poner el codigo del espiral
            %Tamaño de Juntas
            L1 = 25.5;
            L2 = 88.1;
            L3 = 153.59;
            
            t = 1;    % Variable de tiempo
            r = 30;   % Radio de Espiral
            dz = 1.5;   %Delta Z que afecta el cambio de distancia en z
            
            % Coordenadas Iniciales
            Px = r + 100;
            Py = 50;
            Pz = 200;
            
            device=serialport("COM3",9600);
            
            for c = 0:pi/6:12*pi  % Increase the range of c for a larger spiral
                
                t = t + 1;
            
                % Update coordinates along the spiral
                Px = (r*cos(c)) + 100;      % X coordinate of the spiral
                Py = (r*sin(c)) + 50;       % Y coordinate of the spiral
                Pz = Pz - dz;               % Decrease Z coordinate of the spiral
            
                X(t) = Px;   % Variable to store the X coordinate
                Y(t) = Py;   % Variable to store the Y coordinate
                Z(t) = Pz;   % Variable to store the Z coordinate
            
            
                % Retrieve the first angle Q1
                q1 = atan2(Py, Px)
            
                Z1 = Px*cos(q1) + Py*sin(q1);
                Z2 = Pz - L1;
            
                % Retrieve the second angle Q3
                C3 = (Z1^2 + Z2^2 - L2^2 - L3^2) / (2 * L2 * L3);
                S3 = sqrt(1 - C3^2);
                q3 = atan2(S3, C3)
            
                % Retrieve the third angle Q2
                B1 = L2 + L3 * C3;
                B2 = L3 * S3;
            
                S2 = (B1 * Z2 + B2 * Z1) / (B1^2 + B2^2);
                C2 = (B1 * Z1 - B2 * Z2) / (B1^2 + B2^2);
                q2 = atan2(S2, C2)
            
                plot3(app.axesTrayectoria,X(2:t),Y(2:t),Z(2:t),'-o','Color',[0.5 0 0.8]);
                %plot3(X(2:t),Y(2:t),Z(2:t)) %Plot de Trazo
                pause(2.0)
                
                q11 = rad2deg(q1);
                q22 = rad2deg(q2);
                q33 = rad2deg(q3);
            
                theta_array=strcat("<",int2str(q11),",",int2str(q22),",",int2str(q33+90),">")
                writeline(device,theta_array) 
                
            end

            clear device
            
        end

        % Button pushed function: ConstelacinButton
        function ConstelacinButtonPushed(app, event)
            %Aquí poner el codigo de la constelación
            L1 = 25.5;
            L2 = 88.1;
            L3 = 153.59;
            % Coordenadas del primer punto
            P1x = -140;
            P1y = 110;
            P1z = 155;
            
            % Coordenadas del segundo punto
            P2x = 45;
            P2y = 140; %88.1
            P2z = 205; %179.09
            
            m = (P2z-P1z)/(P2x-P1x)   % Slope in x direction
            n = (P2z-P1z)/(P2y-P1y)  % Slope in y direction
            b = P2z-m*P2x-n*P2y   % z-intercept
            
            % coordenadas de x & y
            x = linspace(-140,45, 5)
            y = linspace(110,140, 5)
            
            % Calcular coordenadas de z
            z = m * x + n * y + b

            t = 1;
            device=serialport("COM3",9600);
            
            for i=1:1:5 
                
                t = t+1;
                
                Px = x(i);   %Xi coordinate of the line
                Py = y(i);   %Yi coordinate of the line
                Pz = z(i);   %Zi coordinate of the line
                
                X(t) = Px;             %Buffer para guardar coordenada X 
                Y(t) = Py;             %Buffer para guardar coordenada Y 
                Z(t) = Pz;             %Buffer para guardar coordenada Z 
                
                % Retrieve of the first angle Q1
                q1 = atan2(Py,Px);
                
                Z1 = Px*cos(q1)+Py*sin(q1);
                Z2 = Pz-L1;
            
                % Retrieve of the second angle Q3
                C3 = (Z1^2 + Z2^2 - L2^2 - L3^2)/(2*L2*L3);
                S3 = sqrt(1-C3^2);
                q3 = atan2(S3,C3);
            
               % Retrieve if the third angle Q2 
                B1 = L2 + L3*C3;
                B2 = L3*S3;
            
                S2 = (B1*Z2 + B2*Z1)/(B1^2 + B2^2);
                C2 = (B1*Z1 - B2*Z2)/(B1^2 + B2^2);
                q2 = atan2(S2,C2);
                
                %Esto es el plot de la línea
                plot3(app.axesTrayectoria,X(2:t),Y(2:t),Z(2:t),'-o','Color',[0.5 0 0.8])   
                pause(2.0)

                
                q11 = rad2deg(q1);
                q22 = rad2deg(q2);
                q33 = rad2deg(q3);
                
                theta_array=strcat("<",int2str(q11),",",int2str(q22),",",int2str(q33+90),">")
                writeline(device,theta_array) 
                
            end
             
            clear device
            
        end

        % Callback function
        function MOVERLineaPushed(app, event)
            %Línea Custome-made
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Image = uiimage(app.IntroTab);
            app.Image.ImageSource = 'C:\Users\Vivi\OneDrive - Instituto Tecnologico y de Estudios Superiores de Monterrey\DECIMO SEMESTRE\Robotica\git\robotcito-rigels\GUI2\estrellitas.jpg';
            app.RIGELSLabel = uilabel(app.IntroTab);
            app.RIGELSLabel.Text = 'R I G E L S';
            app.presentaLabel = uilabel(app.IntroTab);
            app.presentaLabel.Text = 'presenta:';
            app.ESTRELLITALabel = uilabel(app.IntroTab);
            app.ESTRELLITALabel.Text = '" E S T R E L L I T A "';
            app.CinDirTab = uitab(app.TabGroup);
            app.CinDirTab.Title = 'Cin Dir';
            app.ModomanualPanel = uipanel(app.CinDirTab);
            app.ModomanualPanel.Title = 'Modo manual';
            app.MOTOR1SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR1SliderLabel.Text = 'MOTOR 1:';
            app.MOTOR1Slider = uislider(app.ModomanualPanel);
            app.MOTOR1Slider.Limits = [0 180];
            app.MOTOR1Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR1SliderValueChanged, true);
            app.MOTOR2SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR2SliderLabel.Text = 'MOTOR 2:';
            app.MOTOR2Slider = uislider(app.ModomanualPanel);
            app.MOTOR2Slider.Limits = [0 180];
            app.MOTOR2Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR2SliderValueChanged, true);
            app.MOTOR3SliderLabel = uilabel(app.ModomanualPanel);
            app.MOTOR3SliderLabel.Text = 'MOTOR 3:';
            app.MOTOR3Slider = uislider(app.ModomanualPanel);
            app.MOTOR3Slider.Limits = [0 180];
            app.MOTOR3Slider.ValueChangedFcn = createCallbackFcn(app, @MOTOR3SliderValueChanged, true);
            app.POSICINDELEFECTORLabel = uilabel(app.CinDirTab);
            app.POSICINDELEFECTORLabel.Text = 'POSICIÓN DEL EFECTOR';
            app.CoordenadasfinalesPanel = uipanel(app.CinDirTab);
            app.CoordenadasfinalesPanel.Title = 'Coordenadas finales';
            app.XLabel = uilabel(app.CoordenadasfinalesPanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.CoordenadasfinalesPanel);
            app.YLabel.Text = 'Y:';
            app.ZLabel = uilabel(app.CoordenadasfinalesPanel);
            app.ZLabel.Text = 'Z:';
            app.posicinenXLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenXLabel.Text = 'posición en X';
            app.posicinenYLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenYLabel.Text = 'posición en Y';
            app.posicinenZLabel = uilabel(app.CoordenadasfinalesPanel);
            app.posicinenZLabel.Text = 'posición en Z';
            app.CinemticaDirectaPanel = uipanel(app.CinDirTab);
            app.CinemticaDirectaPanel.Title = 'Cinemática Directa';
            app.Thera1EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera1EditFieldLabel.Text = 'Thera 1';
            app.Theta1Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta1Dir.Limits = [0 180];
            app.Thera2EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera2EditFieldLabel.Text = 'Thera 2';
            app.Theta2Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta2Dir.Limits = [0 180];
            app.Thera3EditFieldLabel = uilabel(app.CinemticaDirectaPanel);
            app.Thera3EditFieldLabel.Text = 'Thera 3';
            app.Theta3Dir = uieditfield(app.CinemticaDirectaPanel, 'numeric');
            app.Theta3Dir.Limits = [0 180];
            app.MOVEButton = uibutton(app.CinemticaDirectaPanel, 'push');
            app.MOVEButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEButtonPushed, true);
            app.MOVEButton.Text = 'MOVE';
            app.HOMEButton = uibutton(app.CinDirTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.axesDir = uiaxes(app.CinDirTab);
            xlabel(app.axesDir, 'X')
            ylabel(app.axesDir, 'Y')
            zlabel(app.axesDir, 'Z')
            app.CinInvTab = uitab(app.TabGroup);
            app.CinInvTab.Title = 'Cin Inv';
            app.CinemticaInversaPanel = uipanel(app.CinInvTab);
            app.CinemticaInversaPanel.Title = 'Cinemática Inversa';
            app.XEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.XEditFieldLabel.Text = 'X';
            app.Xinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.PosicindeseadadelefectorfinalLabel = uilabel(app.CinemticaInversaPanel);
            app.PosicindeseadadelefectorfinalLabel.Text = 'Posición deseada del efector final:';
            app.SENDButton = uibutton(app.CinemticaInversaPanel, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.YEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Yinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.CinemticaInversaPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.Zinv = uieditfield(app.CinemticaInversaPanel, 'numeric');
            app.PosiblessolucionesDropDownLabel = uilabel(app.CinInvTab);
            app.PosiblessolucionesDropDownLabel.Text = 'Posibles soluciones';
            app.PosiblessolucionesDropDown = uidropdown(app.CinInvTab);
            app.PosiblessolucionesDropDown.Items = {'Seleccione', 'Solución 1', 'Solución 2', 'Solución 3', 'Solución 4'};
            app.PosiblessolucionesDropDown.ValueChangedFcn = createCallbackFcn(app, @PosiblessolucionesDropDownValueChanged, true);
            app.PosiblessolucionesDropDown.Value = 'Seleccione';
            app.ngulosporjuntaPanel = uipanel(app.CinInvTab);
            app.ngulosporjuntaPanel.Title = 'Ángulos por junta';
            app.Thera1EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera1EditField_2Label.Text = 'Thera 1';
            app.Theta1Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta1Inv.Editable = 'off';
            app.Thera2EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera2EditField_2Label.Text = 'Thera 2';
            app.Theta2Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta2Inv.Editable = 'off';
            app.Thera3EditField_2Label = uilabel(app.ngulosporjuntaPanel);
            app.Thera3EditField_2Label.Text = 'Thera 3';
            app.Theta3Inv = uieditfield(app.ngulosporjuntaPanel, 'numeric');
            app.Theta3Inv.Editable = 'off';
            app.PosiblessolucionesPanel = uipanel(app.CinInvTab);
            app.PosiblessolucionesPanel.Title = 'Posibles soluciones:';
            app.Solucin1Panel = uipanel(app.PosiblessolucionesPanel);
            app.Solucin1Panel.Title = 'Solución 1';
            app.Thera1EditField_2Label_2 = uilabel(app.Solucin1Panel);
            app.Thera1EditField_2Label_2.Text = 'Thera 1';
            app.Theta1Sol1 = uieditfield(app.Solucin1Panel, 'numeric');
            app.Theta1Sol1.Editable = 'off';
            app.Thera2EditField_2Label_2 = uilabel(app.Solucin1Panel);
            app.Thera2EditField_2Label_2.Text = 'Thera 2';
            app.Theta2Sol1 = uieditfield(app.Solucin1Panel, 'numeric');
            app.Theta2Sol1.Editable = 'off';
            app.Thera3EditField_2Label_2 = uilabel(app.Solucin1Panel);
            app.Thera3EditField_2Label_2.Text = 'Thera 3';
            app.Theta3Sol1 = uieditfield(app.Solucin1Panel, 'numeric');
            app.Theta3Sol1.Editable = 'off';
            app.Solucin2Panel = uipanel(app.PosiblessolucionesPanel);
            app.Solucin2Panel.Title = 'Solución 2';
            app.Thera1EditField_2Label_3 = uilabel(app.Solucin2Panel);
            app.Thera1EditField_2Label_3.Text = 'Thera 1';
            app.Theta1Sol2 = uieditfield(app.Solucin2Panel, 'numeric');
            app.Theta1Sol2.Editable = 'off';
            app.Thera2EditField_2Label_3 = uilabel(app.Solucin2Panel);
            app.Thera2EditField_2Label_3.Text = 'Thera 2';
            app.Theta2Sol2 = uieditfield(app.Solucin2Panel, 'numeric');
            app.Theta2Sol2.Editable = 'off';
            app.Thera3EditField_2Label_3 = uilabel(app.Solucin2Panel);
            app.Thera3EditField_2Label_3.Text = 'Thera 3';
            app.Theta3Sol2 = uieditfield(app.Solucin2Panel, 'numeric');
            app.Theta3Sol2.Editable = 'off';
            app.Solucin3Panel = uipanel(app.PosiblessolucionesPanel);
            app.Solucin3Panel.Title = 'Solución 3';
            app.Thera1EditField_2Label_4 = uilabel(app.Solucin3Panel);
            app.Thera1EditField_2Label_4.Text = 'Thera 1';
            app.Theta1Sol3 = uieditfield(app.Solucin3Panel, 'numeric');
            app.Theta1Sol3.Editable = 'off';
            app.Thera2EditField_2Label_4 = uilabel(app.Solucin3Panel);
            app.Thera2EditField_2Label_4.Text = 'Thera 2';
            app.Theta2Sol3 = uieditfield(app.Solucin3Panel, 'numeric');
            app.Theta2Sol3.Editable = 'off';
            app.Thera3EditField_2Label_4 = uilabel(app.Solucin3Panel);
            app.Thera3EditField_2Label_4.Text = 'Thera 3';
            app.Theta3Sol3 = uieditfield(app.Solucin3Panel, 'numeric');
            app.Theta3Sol3.Editable = 'off';
            app.Solucin4Panel = uipanel(app.PosiblessolucionesPanel);
            app.Solucin4Panel.Title = 'Solución 4';
            app.Thera1EditField_2Label_5 = uilabel(app.Solucin4Panel);
            app.Thera1EditField_2Label_5.Text = 'Thera 1';
            app.Theta1Sol4 = uieditfield(app.Solucin4Panel, 'numeric');
            app.Theta1Sol4.Editable = 'off';
            app.Thera2EditField_2Label_5 = uilabel(app.Solucin4Panel);
            app.Thera2EditField_2Label_5.Text = 'Thera 2';
            app.Theta2Sol4 = uieditfield(app.Solucin4Panel, 'numeric');
            app.Theta2Sol4.Editable = 'off';
            app.Thera3EditField_2Label_5 = uilabel(app.Solucin4Panel);
            app.Thera3EditField_2Label_5.Text = 'Thera 3';
            app.Theta3Sol4 = uieditfield(app.Solucin4Panel, 'numeric');
            app.Theta3Sol4.Editable = 'off';
            app.solElegida = uilabel(app.CinInvTab);
            app.solElegida.Text = 'SOLUCIÓN ELEGIDA:  Ninguna';
            app.axesInv = uiaxes(app.CinInvTab);
            xlabel(app.axesInv, 'X')
            ylabel(app.axesInv, 'Y')
            zlabel(app.axesInv, 'Z')
            app.TrayectoriasTab = uitab(app.TabGroup);
            app.TrayectoriasTab.Title = 'Trayectorias';
            app.TrayectoriasPanel = uipanel(app.TrayectoriasTab);
            app.TrayectoriasPanel.Title = 'Trayectorias';
            app.SeleccionalatrayectoriaLabel = uilabel(app.TrayectoriasPanel);
            app.SeleccionalatrayectoriaLabel.Text = 'Selecciona la trayectoria:';
            app.CirculoButton = uibutton(app.TrayectoriasPanel, 'push');
            app.CirculoButton.ButtonPushedFcn = createCallbackFcn(app, @CirculoButtonPushed, true);
            app.CirculoButton.Text = 'Círculo';
            app.EspiralButton = uibutton(app.TrayectoriasPanel, 'push');
            app.EspiralButton.ButtonPushedFcn = createCallbackFcn(app, @EspiralButtonPushed, true);
            app.EspiralButton.Text = 'Espiral';
            app.ConstelacinButton = uibutton(app.TrayectoriasPanel, 'push');
            app.ConstelacinButton.ButtonPushedFcn = createCallbackFcn(app, @ConstelacinButtonPushed, true);
            app.ConstelacinButton.Text = 'Constelación';
            app.axesTrayectoria = uiaxes(app.TrayectoriasTab);
            xlabel(app.axesTrayectoria, 'X')
            ylabel(app.axesTrayectoria, 'Y')
            zlabel(app.axesTrayectoria, 'Z')
            app.axesTrayectoria.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end